classdef Motorpruefstand < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure    matlab.ui.Figure
        loadButton  matlab.ui.control.Button
        ListBox     matlab.ui.control.ListBox
        plotButton  matlab.ui.control.Button
        UIAxes      matlab.ui.control.UIAxes
        saveButton  matlab.ui.control.Button
    end

    
    properties (Access = private)
        data % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: loadButton
        function loadButtonPushed(app, event)
            answer = loadAndJoin();
            filename = strcat(answer{1},'.mat');
            app.data = load(filename).compMeasurement;
            app.ListBox.Enable = 'on';
            app.ListBox.Items = fieldnames(app.data);
        end

        % Button pushed function: plotButton
        function plotButtonPushed(app, event)
            achseQuest = plotQuest;
            
            if achseQuest == 2
            yyaxis (app.UIAxes, 'right')
            yAxesData = app.data.(app.ListBox.Value);
            plot(app.UIAxes,yAxesData,"Color","red");
            else 
            yyaxis (app.UIAxes, 'left')
            yAxesData = app.data.(app.ListBox.Value);
            plot(app.UIAxes,yAxesData,"Color","blue");
            end
            
            
        end

        % Button pushed function: saveButton
        function saveButtonPushed(app, event)
           [file, path]=uiputfile('*.fig;*.png','Save Image');
            pathAndFile = strcat(path,file);
            
            % Create a temporary figure with axes.
            fig = figure;
            fig.Visible = 'off';
            figAxes = axes(fig);
            % Copy all UIAxes children, take over axes limits and aspect ratio.            
            allChildren = app.UIAxes.XAxis.Parent.Children;
            copyobj(allChildren, figAxes)
            figAxes.XLim = app.UIAxes.XLim;
            figAxes.YLim = app.UIAxes.YLim;
            figAxes.ZLim = app.UIAxes.ZLim;
            figAxes.DataAspectRatio = app.UIAxes.DataAspectRatio;


            saveas(fig,pathAndFile);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.loadButton = uibutton(app.UIFigure, 'push');
            app.loadButton.ButtonPushedFcn = createCallbackFcn(app, @loadButtonPushed, true);
            app.loadButton.Text = 'load';
            app.ListBox = uilistbox(app.UIFigure);
            app.ListBox.Items = {};
            app.ListBox.Enable = 'off';
            app.ListBox.Value = {};
            app.plotButton = uibutton(app.UIFigure, 'push');
            app.plotButton.ButtonPushedFcn = createCallbackFcn(app, @plotButtonPushed, true);
            app.plotButton.Text = 'plot';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.saveButton = uibutton(app.UIFigure, 'push');
            app.saveButton.ButtonPushedFcn = createCallbackFcn(app, @saveButtonPushed, true);
            app.saveButton.Text = 'save';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Motorpruefstand

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end